classdef receiver_30_aralik_8fsk_calisiyor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Switch                        matlab.ui.control.Switch
        Label                         matlab.ui.control.Label
        DecodetheMessageButton        matlab.ui.control.Button
        FilterButton                  matlab.ui.control.Button
        ModulationOrderDropDown       matlab.ui.control.DropDown
        ModulationOrderDropDownLabel  matlab.ui.control.Label
        SamplingRateEditField         matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel    matlab.ui.control.Label
        SynchronizeButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fs=44100;
        recObj;
        data;
        f_pilot=17e3;
        start_of_message_index;
        end_of_message_index;
        Processed_S;
        Processed_T;
        Processed_F;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.recObj=audiorecorder(app.fs,16,1);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
                value = app.Switch.Value;
                if(value == "Record")
                    record(app.recObj);
                    msgbox("Recording Started");
                end
                
                if(value=="Stop")
                    stop(app.recObj);
                    msgbox('Recording Stopped');
                    app.data = getaudiodata(app.recObj);
    
                    [S, F, T] = stft(app.data, app.fs, 'Window', blackman(256), 'OverlapLength', 128, 'FFTLength', 512);
                    axes(app.UIAxes); 
                    imagesc(app.UIAxes, T, F, 20*log10(abs(S))); 
                    set(app.UIAxes, 'YDir', 'normal'); 
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'Frequency (Hz)');
                    title(app.UIAxes,'Raw Auido (dB)');
                    xlim(app.UIAxes,[T(1) T(end)]);
                    ylim(app.UIAxes,[F(1) F(end)]);
                    colorbar(app.UIAxes);
                end
                
        end

        % Button pushed function: SynchronizeButton
        function SynchronizeButtonPushed(app, event)
            bpFilter = designfilt('bandpassiir', ...
                      'FilterOrder', 20, ...
                      'HalfPowerFrequency1', app.f_pilot - 5, ...
                      'HalfPowerFrequency2', app.f_pilot + 5, ...
                      'SampleRate', app.fs);

            pilotTone = filtfilt(bpFilter, app.data);
            envelope = abs(hilbert(pilotTone));
            coef = 1;
            % Detect when the envelope changes significantly
            if (app.ModulationOrderDropDown.Value == "2-FSK")
                coef = 1;
            elseif (app.ModulationOrderDropDown.Value == "4-FSK")
                 coef = 0.75;
           
            elseif (app.ModulationOrderDropDown.Value == "8-FSK")
                coef = 0.6;
            end

            sampling_index = app.fs/128;
            threshold = mean(envelope);
            % threshold = 0.001; % Set a suitable threshold
            startEndIndex = find(envelope > threshold);
            startTime = startEndIndex(1)/app.fs;
            endTime = startEndIndex(end)/app.fs;
            start_of_pilot_index_T = (startTime*sampling_index);
            end_of_pilot_index_T = (endTime*sampling_index);
            
            app.start_of_message_index = ceil(start_of_pilot_index_T + sampling_index*coef);
            app.end_of_message_index = floor(end_of_pilot_index_T- sampling_index*coef);
            
            [S,F,T] = stft(app.data, app.fs, 'Window', blackman(256), 'OverlapLength', 128, 'FFTLength', 512);
            
            New_S  = S(:,app.start_of_message_index:app.end_of_message_index);
            New_T = T(app.start_of_message_index:app.end_of_message_index)- T(app.start_of_message_index);

            axes(app.UIAxes); 
            imagesc(app.UIAxes, New_T, F, 20*log10(abs(New_S))); 
            set(app.UIAxes, 'YDir', 'normal'); 
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Frequency (Hz)');
            title(app.UIAxes,'Synchronized Auido (dB)');
            xlim(app.UIAxes,[New_T(1) New_T(end)]);
            ylim(app.UIAxes,[F(1) F(end)]);
            colorbar(app.UIAxes);

        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            frequencies1 = [900, 1400, 1900, 2400, 2900, 3400, 3900, 4400, 4900];
            fskfreqs1 = [(frequencies1-100) (frequencies1+100)];
            
            frequencies2 = [2100, 3100, 4100, 5100, 6100, 7100, 8100, 9100];
            fskfreqs2 = [(frequencies2-150) (frequencies2-300)  (frequencies2+150) (frequencies2+300)];
            
            frequencies3 = [1600,3600,5600,7600,9600,11600];
            fskfreqs3 = [ (frequencies3 + 800) (frequencies3 + 600)  (frequencies3 + 400) (frequencies3 + 200) (frequencies3-800) (frequencies3-600)  (frequencies3-400) (frequencies3-200)];
            

            sampling_index = app.fs/128;
            fskfreqs = 0;
            Fs = app.fs;
            
            if (app.ModulationOrderDropDown.Value == "2-FSK")
                fskfreqs = fskfreqs1;
            elseif (app.ModulationOrderDropDown.Value == "4-FSK")
                fskfreqs = fskfreqs2;
           
            elseif (app.ModulationOrderDropDown.Value == "8-FSK")
                fskfreqs = fskfreqs3;
            end 
              
            filteredAudio = zeros(size(app.data));
            for f = fskfreqs
                bw = 50; % Narrow bandwidth around each frequency
                bpFilt = designfilt('bandpassiir', 'FilterOrder', 6, ...
                                    'HalfPowerFrequency1', f - bw, ...
                                    'HalfPowerFrequency2', f + bw, ...
                                    'SampleRate', Fs);
                filteredAudio = filteredAudio + filtfilt(bpFilt, app.data);
            end
            
            % bpFilt2 = designfilt('bandpassiir', 'FilterOrder', 30, ...
            %                         'HalfPowerFrequency1', min(fskfreqs-50), ...
            %                         'HalfPowerFrequency2', max(fskfreqs+50), ...
            %                         'SampleRate', Fs);
            % filteredAudio = filtfilt(bpFilt2,filteredAudio);
            % 
            
            [S , F, T] =  stft(filteredAudio, Fs, 'Window', blackman(256), 'OverlapLength', 128, 'FFTLength', 512);
            
            app.Processed_F=F;
            app.Processed_S  = S(:,app.start_of_message_index:app.end_of_message_index);
            app.Processed_T = T(app.start_of_message_index:app.end_of_message_index)- T(app.start_of_message_index);
            % a = mean(abs(app.Processed_S),"all");
            % app.Processed_S(abs(app.Processed_S)<a) = app.Processed_S(abs(app.Processed_S)<a)*0;
            % app.Processed_S(abs(app.Processed_S)>a) = app.Processed_S(abs(app.Processed_S)>a);
            % a2 = mean(abs(app.Processed_S),"all");
            
            
            axes(app.UIAxes); 
            imagesc(app.UIAxes,app.Processed_T,F,20*log10(abs(app.Processed_S)));
            set(app.UIAxes, 'YDir', 'normal'); 
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Frequency (Hz)');
            title(app.UIAxes,'Filtered Audio (dB)');
            xlim(app.UIAxes,[app.Processed_T(1) app.Processed_T(end)]);
            ylim(app.UIAxes,[F(1) F(end)]);
            colorbar(app.UIAxes);


        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
          app.fs=app.SamplingRateEditField.Value;
          app.recObj=audiorecorder(app.fs,16,1);

        end

        % Button pushed function: DecodetheMessageButton
        function DecodetheMessageButtonPushed(app, event)
            try
           
                S = app.Processed_S;
                T = app.Processed_T;
                      
                sampling_index = app.fs/128;
                bit_wrt_FSK =NaN;
                FSK_index_log = NaN;
                spacer = NaN;
                
                
                if (app.ModulationOrderDropDown.Value == "2-FSK")
                    bit_wrt_FSK = 7;
                    FSK_index_log = 1;
                    spacer = 1;
                elseif (app.ModulationOrderDropDown.Value == "4-FSK")
                    bit_wrt_FSK = 4;
                    FSK_index_log = 2;
                    spacer = 0.75;
                
                elseif (app.ModulationOrderDropDown.Value == "8-FSK")
                    bit_wrt_FSK = 3;
                    FSK_index_log = 3;
                    spacer = 0.5;
                end
                
                
                time_maximum = (bit_wrt_FSK*spacer)*round(max(T)/(bit_wrt_FSK*spacer));
                how_many_message = time_maximum/spacer;
                
                a = linspace(1,round(how_many_message),round((how_many_message)));
                a = round((a*(sampling_index)  - (1*(sampling_index)/4))*spacer);
                
                frequency_samples_at_center_times = S(:,[a]);
                weightss = abs(frequency_samples_at_center_times);
                [~, maxIndices] = max(weightss, [], 1);


                denom = sum(weightss,1);
                newF = repmat(abs(app.Processed_F),1,how_many_message);
                multi = newF.*weightss;
                numerator = sum(multi,1);
                % weighted_mean = numerator./denom;
                weighted_mean = newF(maxIndices);
                
                table = [900, 1400, 1900, 2400, 2900, 3400, 3900, 4400, 4900];
                table2= [2100, 3100, 4100, 5100, 6100, 7100, 8100, 9100];
                table3 = [1600,3600,5600,7600,9600,11600];
                % 
                meanNonZero = weighted_mean.';
                bits = zeros([size(meanNonZero,1)*FSK_index_log 1]);
                if (FSK_index_log ==1)
                    for i=1:size(meanNonZero,1)
                        idx = meanNonZero(i);
                        if (table(1)-250  <(idx)) &&  ( idx <= (table(1)) + 250)
                                err = idx -table(1);
                        elseif (table(2)-250  <(idx)) &&  ( idx <= (table(2)) + 250)
                                err = idx -table(2);
                         elseif (table(3)-250  <(idx)) &&  ( idx <= (table(3)) + 250)
                                err = idx -table(3);
                        elseif (table(4)-250  <(idx)) &&  ( idx <= (table(4)) + 250)
                                err = idx -table(4);
                         elseif (table(5)-250  <(idx)) &&  ( idx <= (table(5)) + 250)
                                err = idx -table(5);
                         elseif (table(6)-250  <(idx)) &&  ( idx <= (table(6)) + 250)
                                err = idx -table(6);
                         elseif (table(7)-250  <(idx)) &&  ( idx <= (table(7)) + 250)
                                err = idx -table(7);
                         elseif (table(8)-250  <(idx)) &&  ( idx <= (table(8)) + 250)
                                err = idx -table(8);
                         elseif (table(9)-250  <(idx)) &&  ( idx <= (table(9)) + 250)
                                err = idx -table(9);
                        end
                         if (err>0)
                            bits(i) = 1;
                         else
                            bits(i) = 0;
                         end
                    end
                elseif(FSK_index_log==3)
                
                 for i=1:size(meanNonZero,1)
                    idx = meanNonZero(i);
                    if (table3(1)-1000  <(idx)) &&  ( idx <= (table3(1)) + 1000)
                            err = idx -table3(1);
                    elseif (table3(2)-1000  <=(idx)) &&  ( idx <= (table3(2)) + 1000)
                            err = idx -table3(2);
                    elseif (table3(3)-1000  <=(idx)) &&  ( idx <= (table3(3)) + 1000)
                            err = idx -table3(3);
                    elseif (table3(4)-1000  <=(idx)) &&  ( idx <= (table3(4)) + 1000)
                            err = idx -table3(4);
                    elseif (table3(5)-1000  <(idx)) &&  ( idx <= (table3(5)) + 1000)
                            err = idx -table3(5);
                    elseif (table3(6)-1000  <=(idx)) &&  ( idx <= (table3(6)) + 1000)
                            err = idx -table3(6);
                    
                    end


                     if (err>0)&&(err<300)
                        bits((3*i-2):(3*i-2)+2) = [1 0 0];
                     elseif (err>=300)&&(err<500)
                        bits((3*i-2):(3*i-2)+2) = [1 0 1];
                     elseif (err>=500)&&(err<700)
                        bits((3*i-2):(3*i-2)+2) = [1 1 0];
                     elseif(err>=700)
                         bits((3*i-2):(3*i-2)+2) = [1 1 1];
                     elseif (err<0)&&(err>-300)
                        bits((3*i-2):(3*i-2)+2) =[0 1 1];
                     elseif (err<=-300)&&(err>-500)
                        bits((3*i-2):(3*i-2)+2) =[0 1 0]; 
                
                     elseif (err<=-500)&&(err>-700)
                        bits((3*i-2):(3*i-2)+2) =[0 0 1]; 
                     elseif (err<=-700)
                         bits((3*i-2):(3*i-2)+2) =[0 0 0]; 
                
                     end
                 end
                else
                    for i=1:size(meanNonZero,1)
                    idx = meanNonZero(i);
                    if (table2(1)-500  <(idx)) &&  ( idx <= (table2(1)) + 500)
                            err = idx -table2(1);
                    elseif (table2(2)-500  <(idx)) &&  ( idx <= (table2(2)) + 500)
                            err = idx -table2(2);
                    elseif (table2(3)-500  <(idx)) &&  ( idx <= (table2(3)) + 500)
                            err = idx -table2(3);
                    elseif (table2(4)-500  <(idx)) &&  ( idx <= (table2(4)) + 500)
                            err = idx -table2(4);
                    elseif (table2(5)-500  <(idx)) &&  ( idx <= (table2(5)) + 500)
                            err = idx -table2(5);
                    elseif (table2(6)-500  <(idx)) &&  ( idx <= (table2(6)) + 500)
                            err = idx -table2(6);
                    elseif (table2(7)-500  <(idx)) &&  ( idx <= (table2(7)) + 500)
                            err = idx -table2(7);
                    elseif (table2(8)-500  <(idx)) &&  ( idx <= (table2(8)) + 500)
                            err = idx -table2(8);
                    end
                
                
                     if (err>0)&&(err<225)
                        bits((2*i-1):(2*i-1)+1) = [1 0];
                     elseif (err>=225)
                        bits((2*i-1):(2*i-1)+1) = [1 1];
                     elseif (err<0)&&(err>-225)
                        bits((2*i-1):(2*i-1)+1) = [0 1];
                     elseif (err<=-225)
                        bits((2*i-1):(2*i-1)+1) =[0 0]; 
                     end
                    end
                end


                bits= reshape(bits,FSK_index_log*bit_wrt_FSK,[]);
                [rows, cols] = size(bits);  % Get size of matrix
                decimals = zeros([cols 1]);
                if (FSK_index_log == 1)
                    decimals = bit2int(bits,bit_wrt_FSK);
                elseif (FSK_index_log == 2)
                    exponents = [64,16,4,1];
                    [rows, cols] = size(bits);  % Get size of matrix
                    for i=1:cols
                        temp_bit = bits(:,i);
                        reshaped_temp_bit = reshape(temp_bit,2,[]);
                        coef = reshaped_temp_bit(1,:)*2+reshaped_temp_bit(2,:)*1;
                        dec = (coef).*(exponents);
                        decimals(i) = sum(dec);
                    end
                elseif (FSK_index_log == 3)
                    exponents = [64,8,1];
                    [rows, cols] = size(bits);  % Get size of matrix
                    for i=1:cols
                        temp_bit = bits(:,i);
                        reshaped_temp_bit = reshape(temp_bit,3,[]);
                        coef = reshaped_temp_bit(1,:)*4+reshaped_temp_bit(2,:)*2 +reshaped_temp_bit(3,:)*1;
                        dec = (coef).*(exponents);
                        decimals(i) = sum(dec);
                    end       
                
                    
                end
                     
                
                values = num2cell(1:85);
                            
                keys = {'A', 'B', 'C', 'Ç', 'D', 'E', 'F', 'G', 'Ğ', 'H', ...
                        'I', 'İ', 'J', 'K', 'L', 'M', 'N', 'O', 'Ö', 'P', ...
                        'R', 'S', 'Ş', 'T', 'U', 'Ü', 'V', 'Y', 'Z', ...
                        'a', 'b', 'c', 'ç', 'd', 'e', 'f', 'g', 'ğ', 'h', ...
                        'i', 'ı', 'j', 'k', 'l', 'm', 'n', 'o', 'ö', 'p', ...
                        'r', 's', 'ş', 't', 'u', 'ü', 'v', 'y', 'z', ...
                        '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', ...
                        '.', ',', ';', ':', '?', '!', '-', '_', '(', ')', ...
                        '[', ']', '{', '}', '"', '''', ' '};
                
                turkish_dict_Decode = containers.Map(values,keys);
                [rowd, cold] = size(decimals);
                s = rowd*cold;
                mesaj="";
                for i=1:s
                    mesaj = mesaj + turkish_dict_Decode(decimals(i));
                end
                Message="Message: "+mesaj;
                msgbox(Message);
            catch
                Message="Failed to decode the message.";
                msgbox(Message);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SynchronizeButton = uibutton(app.UIFigure, 'push');
            app.SynchronizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronizeButtonPushed, true);
            app.SynchronizeButton.Text = 'Synchronize';
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate:';
            app.SamplingRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField.Limits = [80 Inf];
            app.SamplingRateEditField.ValueDisplayFormat = '%.0f';
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.SamplingRateEditField.Value = 44100;
            app.ModulationOrderDropDownLabel = uilabel(app.UIFigure);
            app.ModulationOrderDropDownLabel.Text = 'Modulation Order:';
            app.ModulationOrderDropDown = uidropdown(app.UIFigure);
            app.ModulationOrderDropDown.Items = {'2-FSK', '4-FSK', '8-FSK'};
            app.ModulationOrderDropDown.Value = '2-FSK';
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.DecodetheMessageButton = uibutton(app.UIFigure, 'push');
            app.DecodetheMessageButton.ButtonPushedFcn = createCallbackFcn(app, @DecodetheMessageButtonPushed, true);
            app.DecodetheMessageButton.Text = 'Decode the Message';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Record', 'Stop'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = receiver_30_aralik_8fsk_calisiyor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end